classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        GridLayout2    matlab.ui.container.GridLayout
        TabGroup       matlab.ui.container.TabGroup
        Tab            matlab.ui.container.Tab
        Tab2           matlab.ui.container.Tab
        CheckBox       matlab.ui.control.CheckBox
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button3        matlab.ui.control.RadioButton
        Button2        matlab.ui.control.RadioButton
        Button         matlab.ui.control.RadioButton
        UITable        matlab.ui.control.Table
        LogsPanel      matlab.ui.container.Panel
        GridLayout3    matlab.ui.container.GridLayout
        LogsTextArea   matlab.ui.control.TextArea
        ResetButton    matlab.ui.control.Button
        LoadButton     matlab.ui.control.Button
        PathEditField  matlab.ui.control.EditField
        RightPanel     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        DataTable       table
        DataFilePath    string
        LogHistory      string = ""
    end
    
    methods (Access = private)
        
        function logMessage(app, msg)
            timestamp = datestr(now,'[HH:MM:SS] ');
            newMsg = timestamp + string(msg);
            if strlength(app.LogHistory) > 0
                app.LogHistory = newMsg + newline + app.LogHistory;
            else
                app.LogHistory = newMsg;
            end
            app.LogsTextArea.Value = splitlines(app.LogHistory);
        end

        function loadCsv(app, fullpath)
            % Normalize path
            fullpath = string(fullfile(char(fullpath)));
            if ~isfile(fullpath)
                error('FileNotFound:CSV','File not found: %s', fullpath);
            end
    
            % Read CSV with original variable names preserved
            T = readtable(fullpath, 'PreserveVariableNames', true);
    
            % Save to properties
            app.DataTable    = T;
            app.DataFilePath = fullpath;
    
            % Reflect path to EditField
            app.PathEditField.Value = char(fullpath);
    
            % Log
            app.logMessage("Loaded CSV: " + fullpath);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            try
                startFolder = pwd+"\Data";
                [fname, fpath] = uigetfile({'*.csv','CSV files (*.csv)'; '*.*','All Files'}, ...
                                           'Select data file', startFolder);
                if isequal(fname,0); return; end
                fullpath = fullfile(fpath, fname);
                app.loadCsv(fullpath);
            catch ME
                uialert(app.UIFigure, ME.message, 'Load Failed', 'Icon', 'error');
                app.logMessage("ERROR: " + ME.message);
            end
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            p = string(strtrim(app.PathEditField.Value));
            if strlength(p)==0; return; end
            try
                app.loadCsv(p);
            catch ME
                uialert(app.UIFigure, ME.message, 'Load Failed', 'Icon', 'error');
                if isprop(app,'LogsTextArea'); app.logMessage("ERROR: " + ME.message); end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RightPanel = uipanel(app.GridLayout);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.PathEditField = uieditfield(app.GridLayout2, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.Text = 'Reset';
            app.LogsPanel = uipanel(app.GridLayout2);
            app.LogsPanel.Title = 'Logs';
            app.GridLayout3 = uigridlayout(app.LogsPanel);
            app.LogsTextArea = uitextarea(app.GridLayout3);
            app.LogsTextArea.Editable = 'off';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ButtonGroup = uibuttongroup(app.GridLayout2);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.CheckBox = uicheckbox(app.GridLayout2);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end