classdef Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        GridLayout2     matlab.ui.container.GridLayout
        TabGroup2       matlab.ui.container.TabGroup
        V2Tab           matlab.ui.container.Tab
        V3Tab           matlab.ui.container.Tab
        UITable         matlab.ui.control.Table
        LogsPanel       matlab.ui.container.Panel
        GridLayout3     matlab.ui.container.GridLayout
        LogsTextArea    matlab.ui.control.TextArea
        LoadButton      matlab.ui.control.Button
        ClearButton     matlab.ui.control.Button
        PathEditField   matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        RightPanel      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        DataTable       table
        DataFilePath    string
        LogHistory      string = ""
    end
    
    methods (Access = private)
        
        function logMessage(app, msg)
            timestamp = datestr(now,'[HH:MM:SS] ');
            newMsg = timestamp + string(msg);
            
            if strlength(app.LogHistory) > 0
                app.LogHistory = app.LogHistory + newline + newMsg;
            else
                app.LogHistory = newMsg;
            end

            app.LogsTextArea.Value = splitlines(app.LogHistory);
            drawnow;
            app.LogsTextArea.Value = app.LogsTextArea.Value; % Scroll to end
        end

        function loadCsv(app, fullpath)
            % Normalize path
            fullpath = string(fullfile(char(fullpath)));
            if ~isfile(fullpath)
                error('FileNotFound:CSV','File not found: %s', fullpath);
            end
    
            % Read CSV with original variable names preserved
            T = readtable(fullpath, 'PreserveVariableNames', true);
    
            % Save to properties
            app.DataTable    = T;
            app.DataFilePath = fullpath;
    
            % Reflect path to EditField
            app.PathEditField.Value = char(fullpath);
    
            % Log
            app.logMessage("Loaded CSV: " + fullpath);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            try
                startFolder = pwd+"\Data";
                [fname, fpath] = uigetfile({'*.csv','CSV files (*.csv)'; '*.*','All Files'}, ...
                                           'Select data file', startFolder);
                if isequal(fname,0); return; end
        
                fullpath = fullfile(fpath, fname);
                app.loadCsv(fullpath);
            catch ME
                uialert(app.UIFigure, ME.message, 'Load Failed', 'Icon', 'error');
                app.logMessage("ERROR: " + ME.message);
            end
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            p = string(strtrim(app.PathEditField.Value));
            if strlength(p)==0; return; end
            try
                app.loadCsv(p);
            catch ME
                uialert(app.UIFigure, ME.message, 'Load Failed', 'Icon', 'error');
                if isprop(app,'LogsTextArea'); app.logMessage("ERROR: " + ME.message); end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {581, 581};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {501, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.EditFieldLabel = uilabel(app.GridLayout2);
            app.EditFieldLabel.Text = 'Edit Field';
            app.PathEditField = uieditfield(app.GridLayout2, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.ClearButton = uibutton(app.GridLayout2, 'push');
            app.ClearButton.Text = 'Clear';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load .csv file'};
            app.LoadButton.Text = 'Load';
            app.LogsPanel = uipanel(app.GridLayout2);
            app.LogsPanel.Title = 'Logs';
            app.GridLayout3 = uigridlayout(app.LogsPanel);
            app.LogsTextArea = uitextarea(app.GridLayout3);
            app.LogsTextArea.Editable = 'off';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Order'; 'Attributes'; 'Select'};
            app.UITable.RowName = {};
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.V2Tab = uitab(app.TabGroup2);
            app.V2Tab.Title = 'V2';
            app.V3Tab = uitab(app.TabGroup2);
            app.V3Tab.Title = 'V3';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end